classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Spinner          matlab.ui.control.Spinner
        Label_2          matlab.ui.control.Label
        Button_11        matlab.ui.control.Button
        Button_10        matlab.ui.control.Button
        Lamp             matlab.ui.control.Lamp
        Button_9         matlab.ui.control.Button
        Button_8         matlab.ui.control.Button
        EditField        matlab.ui.control.EditField
        Label            matlab.ui.control.Label
        FtSpinner        matlab.ui.control.Spinner
        FtSpinnerLabel   matlab.ui.control.Label
        ApSpinner        matlab.ui.control.Spinner
        ApSpinnerLabel   matlab.ui.control.Label
        AsSpinner        matlab.ui.control.Spinner
        AsSpinnerLabel   matlab.ui.control.Label
        Fs2Spinner       matlab.ui.control.Spinner
        Fs2SpinnerLabel  matlab.ui.control.Label
        Fs1SpinnerLabel  matlab.ui.control.Label
        Fs1Spinner       matlab.ui.control.Spinner
        Fp2Spinner       matlab.ui.control.Spinner
        Fp2SpinnerLabel  matlab.ui.control.Label
        Fp1Spinner       matlab.ui.control.Spinner
        Fp1SpinnerLabel  matlab.ui.control.Label
        ButtonGroup_2    matlab.ui.container.ButtonGroup
        Button_7         matlab.ui.control.RadioButton
        Button_6         matlab.ui.control.RadioButton
        Button_5         matlab.ui.control.Button
        ButtonGroup      matlab.ui.container.ButtonGroup
        Button_4         matlab.ui.control.RadioButton
        Button_3         matlab.ui.control.RadioButton
        Button_2         matlab.ui.control.RadioButton
        Button           matlab.ui.control.Button
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes_4         matlab.ui.control.UIAxes
        UIAxes_3         matlab.ui.control.UIAxes
        UIAxes_2         matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        mode2
        mode3
        mode4
        Fp
        Fp1
        Fp2
        Fs
        Fs1
        Fs2
        Ft
        As
        Ap
        wp
        ws
        wn
        n
        b
        a
        B
        A
        h
        w
        y
        y1
        t
        Y1
        fs
        N
        y2
        f1
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %赋值初始化
            app.Fp=app.Fp1Spinner.Value;
            app.Fp1=app.Fp1Spinner.Value;
            app.Fp2=app.Fp2Spinner.Value;

            app.Fs=app.Fs1Spinner.Value;
            app.Fs1=app.Fs1Spinner.Value;
            app.Fs2=app.Fs2Spinner.Value;

            app.As=app.AsSpinner.Value;
            app.Ap=app.ApSpinner.Value;
            app.Ft=app.FtSpinner.Value;

            app.mode2=app.Button_2.Value;
            app.mode3=app.Button_3.Value;
            app.mode4=app.Button_4.Value;
            
            if app.mode2==1
                app.wp=2*pi*app.Fp/app.Ft;
                app.ws=2*pi*app.Fs/app.Ft;
                [app.n,app.wn]=ellipord(app.wp,app.ws,app.Ap,app.As,'s');
                [app.b,app.a]=ellip(app.n,app.Ap,app.As,app.wn,'s');
                [app.B,app.A]=bilinear(app.b,app.a,1);
                [app.h,app.w]=freqz(app.B,app.A);
%               滤波器特性  
                plot(app.UIAxes2,app.w*app.Ft/pi/2,abs(app.h));
            end

            if app.mode3==1
                app.wp=2*pi*app.Fp/app.Ft;
                app.ws=2*pi*app.Fs/app.Ft;
                [app.n,app.wn]=ellipord(app.wp,app.ws,app.Ap,app.As,'s');
                [app.b,app.a]=ellip(app.n,app.Ap,app.As,app.wn,'high','s');
                [app.B,app.A]=bilinear(app.b,app.a,1);
                [app.h,app.w]=freqz(app.B,app.A);
%               滤波器特性                  
                plot(app.UIAxes2,app.w*app.Ft/pi/2,abs(app.h));
            end

            if app.mode4==1
                app.wp=[2*pi*app.Fp1/app.Ft,2*pi*app.Fp2/app.Ft];
                app.ws=[2*pi*app.Fs1/app.Ft,2*pi*app.Fs2/app.Ft];
                [app.n,app.wn]=ellipord(app.wp,app.ws,app.Ap,app.As,'s');
                [app.b,app.a]=ellip(app.n,app.Ap,app.As,app.wn,'s');
                [app.B,app.A]=bilinear(app.b,app.a,1);
                [app.h,app.w]=freqz(app.B,app.A);
%               滤波器特性                  
                plot(app.UIAxes2,app.w*app.Ft/pi/2,abs(app.h));
            end

            app.y=filter(app.B,app.A,app.y1);
            app.n=0:length(app.y1)-1;
            app.t=(0:length(app.y1)-1)/app.fs;
            app.Y1=fft(app.y);
            plot(app.UIAxes_3,app.t,app.y);
            plot(app.UIAxes_4,app.t,app.Y1);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            %导入录音
            file = app.EditField.Value;
            [app.y1,app.fs] = audioread(file);

            app.N= length(app.y1);
            app.f1= (0:app.N-1)*app.fs/app.N;
            app.y2 = fft (app.y1,app.N);
            plot(app.UIAxes,app.y1);
            plot(app.UIAxes_2,app.f1,abs(app.y2));
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)

            app.fs = 48000;  % 采样频率
            long = 4; % 时间长度(秒） 
            % 创建一个录音文件：fs =48000Hz, 16-bit, 单通道
            recorderObj = audiorecorder(app.fs, 16, 2);   
            app.Lamp.Color='1,0,0';
            recordblocking(recorderObj, long); % 录音4秒钟
            stop(recorderObj);
            app.Lamp.Color='0,1,0';
            app.y = getaudiodata(recorderObj);
            ymax = max(abs(app.y)); % 归一化
            app.y = app.y/ymax;
            audiowrite('C:\Users\banana\Desktop\数字信号处理\voice.wav', app.y, app.fs); % 存储录音文件
            file = 'C:\Users\banana\Desktop\数字信号处理\voice.wav';
            [app.y1,app.fs] = audioread(file);

            app.N = length(app.y1);

            app.f1= (0:app.N-1)*app.fs/app.N;
            app.y2 = fft (app.y1,app.N);
            plot(app.UIAxes,app.y1);
            plot(app.UIAxes_2,app.f1,abs(app.y2));

        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if app.Button_6.Value==1
                sound(app.y1,app.fs)
            end
            if app.Button_7.Value==1
                sound(app.y,app.fs);
            end
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
             cla(app.UIAxes);
             cla(app.UIAxes_2);
             cla(app.UIAxes_3);
             cla(app.UIAxes_4);
             cla(app.UIAxes2);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            if app.Button_6.Value==1
                clear sound
            end
            if app.Button_7.Value==1
                 clear sound
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '时域语音信号')
            xlabel(app.UIAxes, '时间')
            ylabel(app.UIAxes, '语音信号')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '频域语音信号')
            xlabel(app.UIAxes_2, '频率')
            ylabel(app.UIAxes_2, '语音信号')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTickLabel = {'0'; '1000'; '2000'; '3000'; '4000'; '5000'};
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '滤波后时域语音信号')
            xlabel(app.UIAxes_3, '时间')
            ylabel(app.UIAxes_3, '语音信号')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, '滤波后频域语音信号')
            xlabel(app.UIAxes_4, '频率')
            ylabel(app.UIAxes_4, '语音信号')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XTickLabel = {'0'; '1000'; '2000'; '3000'; '4000'; '5000'};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '滤波器特性')
            xlabel(app.UIAxes2, '幅度')
            ylabel(app.UIAxes2, '频率')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTickLabel = {'0'; '2500'; '5000'};
            app.UIAxes2.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2.YTickLabel = {'0'; '0.12'; '0.24'; '0.36'; '0.48'; '0.60'; '0.72'; '0.84'; '0.96'; '1.08'; '1.20'};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '滤波并显示图像';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '滤波器';
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '低通滤波器';
            app.Button_2.Value = true;
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '高通滤波器';
            app.Button_4 = uiradiobutton(app.ButtonGroup);
            app.Button_4.Text = '带通滤波器';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '播放';
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_2.Title = '语音选择';
            app.Button_6 = uiradiobutton(app.ButtonGroup_2);
            app.Button_6.Text = '原始语音';
            app.Button_6.Value = true;
            app.Button_7 = uiradiobutton(app.ButtonGroup_2);
            app.Button_7.Text = '滤波后语音';
            app.Fp1SpinnerLabel = uilabel(app.UIFigure);
            app.Fp1SpinnerLabel.Text = 'Fp1';
            app.Fp1Spinner = uispinner(app.UIFigure);
            app.Fp1Spinner.Value = 1200;
            app.Fp2SpinnerLabel = uilabel(app.UIFigure);
            app.Fp2SpinnerLabel.Text = 'Fp2';
            app.Fp2Spinner = uispinner(app.UIFigure);
            app.Fp2Spinner.Value = 3000;
            app.Fs1Spinner = uispinner(app.UIFigure);
            app.Fs1Spinner.Value = 1000;
            app.Fs1SpinnerLabel = uilabel(app.UIFigure);
            app.Fs1SpinnerLabel.Text = 'Fs1';
            app.Fs2SpinnerLabel = uilabel(app.UIFigure);
            app.Fs2SpinnerLabel.Text = 'Fs2';
            app.Fs2Spinner = uispinner(app.UIFigure);
            app.Fs2Spinner.Value = 3200;
            app.AsSpinnerLabel = uilabel(app.UIFigure);
            app.AsSpinnerLabel.Text = 'As';
            app.AsSpinner = uispinner(app.UIFigure);
            app.AsSpinner.Value = 100;
            app.ApSpinnerLabel = uilabel(app.UIFigure);
            app.ApSpinnerLabel.Text = 'Ap';
            app.ApSpinner = uispinner(app.UIFigure);
            app.ApSpinner.Value = 1;
            app.FtSpinnerLabel = uilabel(app.UIFigure);
            app.FtSpinnerLabel.Text = 'Ft';
            app.FtSpinner = uispinner(app.UIFigure);
            app.FtSpinner.Value = 8000;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '音频位置';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'C:\Users\banana\Desktop\数字信号处理\record.mp3';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '录制语音';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '导入录音';
            app.Lamp = uilamp(app.UIFigure);
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '清空';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '停止';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '时长';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end